classdef TIPexe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        OrUploadAnImageButton           matlab.ui.control.Button
        WelcomePleasechooseasceneforthetourLabel  matlab.ui.control.Label
        Button_12                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FGMaskTab                       matlab.ui.container.Tab
        ClickheretoselectaforegroundobjectButton  matlab.ui.control.Button
        WhendonemovetonextstepLabel     matlab.ui.control.Label
        Button_14                       matlab.ui.control.Button
        STEP1Label                      matlab.ui.control.Label
        BinaryMaskLabel_2               matlab.ui.control.Label
        BinaryMaskLabel                 matlab.ui.control.Label
        TofinishaselectiondoubleclickonthelastpointLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Button_13                       matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        VPTab                           matlab.ui.container.Tab
        Button_25                       matlab.ui.control.Button
        ClickheretocomputethemeshButton  matlab.ui.control.Button
        ClickheretoselectabackgroundButton  matlab.ui.control.Button
        ClickheretoselectavanishingpointButton  matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        STEP2Label                      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Button_15                       matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        threeDimensionalPlot            matlab.ui.container.Tab
        AdjustAzimuthandElevationPanel  matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Button_24                       matlab.ui.control.Button
        XSlider                         matlab.ui.control.Slider
        YSlider                         matlab.ui.control.Slider
        Button_23                       matlab.ui.control.Button
        STEP3Label_2                    matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Button_22                       matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % rois and positions are cell arrays. As an example, positions{1,1}, is a cell...
        % containing the coordinates of each of the points used to mark the
        % ROI around the FIRST object. positions{1,2} is the cell for the
        % SECOND, and so on.
        
        chooseForeground = false;
        image_width;
        image_height;
        rois; %Structure array to store Regions of interest(contains the positions, images, coordinates, everything..)
        vanishing_Point; %Vanishing Point
        imagedata; %pixeldata of img
        Background_Rectangle %Background
        Rectanglecorners; %coordinates of the background rectangle corners
        Intersectionpoints; %coordinates of the intersectionpoints with the imageborder
        listener; %Calls function when Rectangle is moved
        p; % coordinates of 12 points of polygons
        depth = 1000; %room depth/focal length   
        height_background; % height and width of middle face
        width_background; % height and width of middle face
        PW; % 3D model 
        PW_cam; % reset the x/y/z-dierection of axis
        impaintedRoi % image with inpainted ROI
        Spidermesh_plot = {}; %The plothandles of the lines of the spidermesh are saved here
        Intersec_plot = {}; %The plothandles of the intersectionpoints of the spidermesh are saved here
        
        %% Improving visualisation

            up_x 
            up_y
            up_z
            b_x 
            b_y 
            b_z
            r_x
            r_y
            r_z
            l_x
            l_y
            l_z
            m_x
            m_y
            m_z
            I_new_middle
            I_new_right
            I_new_left
            I_new_up
            I_new_down

    end

    methods (Access = public)  
        function updateRectangle(app,~,evt)
            app.Rectanglecorners.top_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.top_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.bottom_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)]; 
            app.Rectanglecorners.bottom_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)];
             if strcmp(evt.EventName, 'MovingROI')
                     ClickheretocomputethemeshButtonPushed(app);
             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_7
        function showImageOil(app, event)
            app.imagedata = imread("images/oil-painting.png");
            [app.image_height, app.image_width, ~] = size(app.imagedata);
            app.TabGroup.SelectedTab = app.FGMaskTab;
            app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_8
        function showImageTUM(app, event)
            app.imagedata = imread("images/uhren-turm.jpg");
            [app.image_height, app.image_width, ~] = size(app.imagedata);
            app.TabGroup.SelectedTab = app.FGMaskTab;
            app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_9
        function showImageMall(app, event)
            app.imagedata = imread("images/shopping-mall.png");
            [app.image_height, app.image_width, ~] = size(app.imagedata);
            app.TabGroup.SelectedTab = app.FGMaskTab;
            app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_12
        function showImageSagrada(app, event)
            app.imagedata = imread("images/sagrada_familia.png");
            [app.image_height, app.image_width, ~] = size(app.imagedata);
            app.TabGroup.SelectedTab = app.FGMaskTab;
            app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_11
        function showImageMoscow(app, event)
            app.imagedata = imread("images/metro-station.png");
             [app.image_height, app.image_width, ~] = size(app.imagedata);
             app.TabGroup.SelectedTab = app.FGMaskTab;
             app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_10
        function showImageDrawing(app, event)
            app.imagedata = imread("images/simple-room.png");
            [app.image_height, app.image_width, ~] = size(app.imagedata);
            app.TabGroup.SelectedTab = app.FGMaskTab;
            app.rois = markMultipleROIs(app.imagedata, app);
            
        end

        % Button pushed function: Button_13, Button_15, Button_22
        function clearAll(app, event)
            delete(app.UIAxes.Children)
            delete(app.UIAxes2.Children)
            delete(app.UIAxes3.Children)
            delete(app.UIAxes_2.Children)
            delete(app.UIAxes_3.Children)
            app.TabGroup.SelectedTab = app.HomeTab;

            %% Resetting all variables and arrays

            app.chooseForeground = false;
            app.image_width = [];
            app.image_height = [];
            app.rois = []; 
            app.vanishing_Point = [];
            app.imagedata = []; 
            app.Background_Rectangle = []; 
            app.Rectanglecorners = []; 
            app.Intersectionpoints = []; 
            app.listener = []; 
            app.p = []; 
            app.height_background = []; 
            app.width_background = [];  
            app.PW = []; 
            app.PW_cam = []; 
            app.impaintedRoi = [];
            app.Spidermesh_plot = {}; 
            app.Intersec_plot = {};
            app.up_x = [];
            app.up_y = [];
            app.up_z = [];
            app.b_x = [];
            app.b_y = [];
            app.b_z = [];
            app.r_x = [];
            app.r_y = [];
            app.r_z = [];
            app.l_x = [];
            app.l_y = [];
            app.l_z = [];
            app.m_x = [];
            app.m_y = [];
            app.m_z = [];
            app.I_new_middle = [];
            app.I_new_right = [];
            app.I_new_left = [];
            app.I_new_up = [];
            app.I_new_down = [];

            
        end

        % Button pushed function: OrUploadAnImageButton
        function uploadImage(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tiff;*.bmp', 'Image Files (*.png, *.jpg, *.jpeg, *.tiff, *.bmp)'});
            if isequal(file, 0)
                disp('Upload aborted');
            else
                fullFileName = fullfile(path, file);
                disp(['Image Path:', fullFileName]);
                app.imagedata = imread(fullFileName);
                [app.image_height, app.image_width, ~] = size(app.imagedata);
                app.TabGroup.SelectedTab = app.FGMaskTab;
                 app.rois = markMultipleROIs(app.imagedata, app);
                
            end
        end

        % Button pushed function: Button_14
        function next(app, event)
            app.TabGroup.SelectedTab = app.VPTab;
            imshow(app.impaintedRoi, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
        end

        % Button pushed function: Button_17
        function prev(app, event)
            app.TabGroup.SelectedTab = app.FGMaskTab;
        end

        % Button pushed function: ClickheretoselectavanishingpointButton
        function ClickheretoselectavanishingpointButtonPushed(app, event)
            % if ~isempty(app.vanishing_Point)
            %     delete(app.vanishing_Point);
            % end
            app.vanishing_Point = drawpoint('Parent',app.UIAxes_2);
        end

        % Button pushed function: ClickheretoselectabackgroundButton
        function ClickheretoselectabackgroundButtonPushed(app, event)
            % if ~isempty(app.Background_Rectangle)
            %     delete(app.Background_Rectangle);
            % end
            app.Background_Rectangle = drawrectangle('Parent',app.UIAxes_2);
            app.listener = addlistener(app.Background_Rectangle,'ROIMoved',@app.updateRectangle);
            app.listener = addlistener(app.Background_Rectangle,'MovingROI',@app.updateRectangle);
            app.Rectanglecorners.top_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.top_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.bottom_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)]; 
            app.Rectanglecorners.bottom_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)];
        end

        % Button pushed function: ClickheretocomputethemeshButton
        function ClickheretocomputethemeshButtonPushed(app, event)
            % Delete existing lines
            for i = 1:length(app.Spidermesh_plot)
                if isgraphics(app.Spidermesh_plot{i})
                    delete(app.Intersec_plot{i});
                    delete(app.Spidermesh_plot{i});
                end
            end
            vanishing_point = app.vanishing_Point.Position;
            corners = [app.Rectanglecorners.top_left;
                       app.Rectanglecorners.top_right;
                       app.Rectanglecorners.bottom_left;
                       app.Rectanglecorners.bottom_right];
        
            % Get image size from app.imagedata
            [app.image_height, app.image_width, ~] = size(app.imagedata);
        
            % Define x and y limits based on image size
            x_limits = [1, app.image_width];
            y_limits = [1, app.image_height];
        
            % Loop over each corner and draw the line from vanishing point to plot boundary
            for i = 1:size(corners, 1)
                ip = DrawLineToBoundary(vanishing_point, corners(i, :), x_limits, y_limits, app.UIAxes_2, i);
                switch i
                    case 1
                        app.Intersectionpoints.top_left = ip;
                    case 2
                        app.Intersectionpoints.top_right = ip;
                    case 3
                        app.Intersectionpoints.bottom_left = ip;    
                    case 4
                        app.Intersectionpoints.bottom_right = ip;    
                end
            end
        
        % Function to calculate intersection and plot line
        function intersectionpoint = DrawLineToBoundary(vanishing_point, corner, x_limits, y_limits, axes_handle, line_ctr)
            % Calculate direction vector from vanishing point to corner
            direction_vector = corner - vanishing_point;
        
            intersections = [];
        
            % Check intersections with image boundaries
                % Intersection with left boundary 
                t_left = (x_limits(1) - vanishing_point(1)) / direction_vector(1);
                y_left = vanishing_point(2) + t_left * direction_vector(2);
                if y_left >= y_limits(1) && y_left <= y_limits(2)
                    intersections = [intersections; x_limits(1), y_left];
                end
        
                % Intersection with right boundary
                t_right = (x_limits(2) - vanishing_point(1)) / direction_vector(1);
                y_right = vanishing_point(2) + t_right * direction_vector(2);
                if y_right >= y_limits(1) && y_right <= y_limits(2)
                    intersections = [intersections; x_limits(2), y_right];
                end
        
            % Handling top and bottom boundaries
                % Intersection with bottom boundary 
                t_bottom = (y_limits(1) - vanishing_point(2)) / direction_vector(2);
                x_bottom = vanishing_point(1) + t_bottom * direction_vector(1);
                if x_bottom >= x_limits(1) && x_bottom <= x_limits(2)
                    intersections = [intersections; x_bottom, y_limits(1)];
                end
        
                % Intersection with top boundary 
                t_top = (y_limits(2) - vanishing_point(2)) / direction_vector(2);
                x_top = vanishing_point(1) + t_top * direction_vector(1);
                if x_top >= x_limits(1) && x_top <= x_limits(2)
                    intersections = [intersections; x_top, y_limits(2)];
                end
        
                if (corner(1)<intersections(1,1)&&corner(1)>vanishing_point(1))||(corner(1)>intersections(1,1)&&corner(1)<vanishing_point(1))
                    intersectionpoint = intersections(1,:);
                else
                    intersectionpoint = intersections(2,:);
                end
            % Plot 
         
            color_lines = app.Background_Rectangle.Color;
            app.Spidermesh_plot{line_ctr} =  plot(axes_handle, [vanishing_point(1), intersectionpoint(1)], [vanishing_point(2), intersectionpoint(2)],'color',color_lines, 'LineWidth', 1);
            app.Intersec_plot{line_ctr} = plot(axes_handle,intersectionpoint(1),intersectionpoint(2),"square",'color','r','LineWidth',2);
        end

        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            %Get coordinates for all coordinate systems, 12 points.
            %Coordinates are saved through luca's data

        if isempty(app.Background_Rectangle)
            app.Background_Rectangle = struct();
            app.Background_Rectangle.Position = [1, 1, 1, 1];
            app.Background_Rectangle.Position(1) = (0.25 * app.image_width);
            app.Background_Rectangle.Position(2) = (0.25 * app.image_height);
            app.Background_Rectangle.Position(3) = (0.5 * app.image_width);
            app.Background_Rectangle.Position(4) = (0.5 * app.image_height);

            app.Background_Rectangle.Color = [0, 0, 0];

            app.Rectanglecorners.top_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.top_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)];
            app.Rectanglecorners.bottom_left = [app.Background_Rectangle.Position(1) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)]; 
            app.Rectanglecorners.bottom_right = [app.Background_Rectangle.Position(1)+app.Background_Rectangle.Position(3) app.Background_Rectangle.Position(2)+app.Background_Rectangle.Position(4)];

            if isempty(app.vanishing_Point)
                app.vanishing_Point = struct('Position', [(0.5 * app.image_width), (0.5 * app.image_height)]);
            end

            ClickheretocomputethemeshButtonPushed(app);
        end

            app.TabGroup.SelectedTab = app.threeDimensionalPlot; %Change current tab
            
            [app.p, app.height_background, app.width_background] = coordinates_Rechnung(app.vanishing_Point, app.image_width, app.image_height, app.Rectanglecorners);
            [H_left, H_down, H_up, H_right, H_middle, P_3D] = world_coordinates_Rechnung(app.p, app.height_background, app.width_background, app.depth);
            P_3D_origin = P_3D;

            [X, Y] = meshgrid(1:app.image_width, 1:app.image_height);
            X = X(:);
            Y = Y(:);


            % Split the picture into five parts.
            [app.I_new_left, PW_left, I1_left, I2_left, I3_left] = left_Rechnung(app.impaintedRoi, app.p, X, Y, H_left);
            app.I_new_left = interpolation(app.I_new_left);

            [app.I_new_down, PW_down, I1_down, I2_down, I3_down] = down_Rechnung(app.impaintedRoi, app.p, X, Y, H_down);
            app.I_new_down = interpolation(app.I_new_down);

            [app.I_new_up, PW_up, I1_up, I2_up, I3_up] = up_Rechnung(app.impaintedRoi, app.p, X, Y, H_up);
            app.I_new_up  = interpolation(app.I_new_up);
            
            [app.I_new_right, PW_right, I1_right, I2_right, I3_right] = right_Rechnung(app.impaintedRoi, app.p, X, Y, H_right);
            app.I_new_right = interpolation(app.I_new_right);
      
            [app.I_new_middle, PW_middle, I1_middle, I2_middle, I3_middle] = middle_Beibehaltung(app.impaintedRoi, app.p, H_middle);
            app.I_new_middle = interpolation(app.I_new_middle);


            % need progress yiyue u progress here
            % if app.picture
            %     [I_new_middle_m, PW_middle_m, I1_middle_m, I2_middle_m, I3_middle_m] = middle_Beibehaltung(app.maskRGB, app.pm, Part_middle);
            %     index_m = min(find(I_new_middle_m(:,1)~=0, 1));
            %     % I_new_middle_m = interpolation( I_new_middle_m(index_m:end,:,:) );

                % geometrische Beziehung
            %     ZM = app.rxm(1)/size(app.imagedata,1)*abs((P(1,1)-P(5,3)))*0.5-100; 
            %     PW1 = [PW_down(1,:),PW_up(1,:),zeros(size(PW_left(1,:))),app.width_background*ones(size(PW_right(1,:))),PW_middle(1,:),PW_middle_m(1,:)];
            %     PW2 = [zeros(size(PW_down(1,:))),app.height_background*ones(size(PW_up(1,:))),PW_left(1,:),PW_right(1,:),PW_middle(2,:),PW_middle_m(2,:)];
            %     PW3 = [PW_down(2,:),PW_up(2,:),PW_left(2,:),PW_right(2,:),zeros(size(PW_middle(1,:))),ZM*ones(size(PW_middle_m(1,:)))];
            %     PW4 = [I1_down',I1_up',I1_left',I1_right',I1_middle',I1_middle_m'];
            %     PW5 = [I2_down',I2_up',I2_left',I2_right',I2_middle',I2_middle_m'];
            %     PW6 = [I3_down',I3_up',I3_left',I3_right',I3_middle',I3_middle_m'];
            %     app.PW = [PW1;PW2;PW3;PW4;PW5;PW6]; 
            %     PW_M = [PW_middle_m;ZM*ones(size(PW_middle_m(1,:)))];
            % else
                % PW1 = [PW_down(1,:), PW_up(1,:), zeros(size(PW_left(1,:))), app.width_background*ones(size(PW_right(1,:))), PW_middle(1,:)];
                % PW2 = [zeros(size(PW_down(1,:))), app.height_background*ones(size(PW_up(1,:))), PW_left(1,:), PW_right(1,:), PW_middle(2,:)];
                % PW3 = [PW_down(2,:), PW_up(2,:), PW_left(2,:), PW_right(2,:), zeros(size(PW_middle(1,:)))];
                % PW4 = [I1_down', I1_up', I1_left', I1_right', I1_middle'];
                % PW5 = [I2_down', I2_up', I2_left', I2_right', I2_middle'];
                % PW6 = [I3_down', I3_up', I3_left', I3_right', I3_middle'];
                % app.PW = [PW1; PW2; PW3; PW4; PW5; PW6]; 
            % end

            % Reset of camera coordinates


            % app.PW_cam(1, :) = double(app.PW(1,:)) - abs(app.vanishing_Point.Position(1)-app.p(1,1));
            % app.PW_cam(2, :) = double(app.PW(2,:)) - abs(app.vanishing_Point.Position(2)-app.p(1,2));
            % app.PW_cam(3, :) = double(app.PW(3,:)) - app.depth;
         
            P_3D(:, 1) = P_3D(:, 1) - abs(app.vanishing_Point.Position(1) - app.p(1, 1));
            P_3D(:, 2) = P_3D(:, 2) - abs(app.vanishing_Point.Position(2) - app.p(1, 2));
            P_3D(:, 3) = P_3D(:, 3) - app.depth;
            R_const = [0 1 0; 1 0 0; 0 0 -1];
            % reset the x/y/z-dierection of axis
            % app.PW_cam = R_const * app.PW_cam; 
            P_3D = R_const * P_3D';
            %no need progress
            % if app.picture
            %     PW_M_cam = R_const * PW_M_cam;
            % end

            if app.chooseForeground 
                for i = 1:app.rois.count
                    foregroundObjImg(app.imagedata, i, H_middle,app);
                end
            end
            % Extract keypoints and depth values that define the top and bottom of the model Define the middle, left, and right sides of the model.
            xmin = P_3D(1,1);
            ymin = P_3D(2,1);
            xmax = P_3D(1,3);
            ymax = P_3D(2,2);
            app.l_z = P_3D(3,12);
            app.r_z = P_3D(3,7);
            down_z = P_3D(3,6);
            app.up_z = P_3D(3,9);
            zmin = app.depth;
       
            %% update both up and down
            app.up_x = [xmax xmax xmax; xmax xmax xmax];
            app.up_y = [ymin ymin ymin; ymax ymax ymax];
            app.up_z = [zmin,(app.up_z+zmin)/2 app.up_z;zmin,(app.up_z+zmin)/2 app.up_z];
            app.b_x = [xmin xmin xmin; xmin xmin xmin];
            app.b_y = [ymin ymin ymin; ymax ymax ymax];
            app.b_z = [zmin,(down_z+zmin)/2 down_z;zmin,(down_z+zmin)/2 down_z];

            if app.chooseForeground 
                for i = 1:app.rois.count
                    foregroundObjCoordinates(i, P_3D_origin, app);
                
                    mask_xmin = min(app.rois.PW_M_cam{i}(1,:));
                    mask_xmax = max(app.rois.PW_M_cam{i}(1,:));
                    mask_ymin = min(app.rois.PW_M_cam{i}(2,:));
                    mask_ymax = max(app.rois.PW_M_cam{i}(2,:));
    
                    ZM2 = app.depth-app.rois.ROI_x_min{i}/size(app.imagedata,1)*(app.depth-down_z);
                    app.rois.middle_mx{i} = [mask_xmin (mask_xmax+mask_xmin)/2 mask_xmax; mask_xmin (mask_xmin+mask_xmax)/2 mask_xmax];
                    app.rois.middle_my{i} = [mask_ymin mask_ymin mask_ymin; mask_ymax mask_ymax mask_ymax];
                    app.rois.middle_mz{i} = [ZM2 ZM2 ZM2;ZM2 ZM2 ZM2];
                end
            end
            %% update right left middle
            app.r_x = [xmin xmin xmin; xmax xmax xmax];
            app.r_y = [ymax ymax ymax; ymax ymax ymax];
            app.r_z = [zmin (app.r_z+zmin)/2 app.r_z; zmin (app.r_z+zmin)/2 app.r_z];
            app.l_x = [xmin xmin xmin; xmax xmax xmax];
            app.l_y = [ymin ymin ymin; ymin ymin ymin];
            app.l_z = [zmin (app.l_z+zmin)/2 app.l_z; zmin (app.l_z+zmin)/2 app.l_z];
            app.m_x = [xmin (xmax+xmin)/2 xmax; xmin (xmin+xmax)/2 xmax];
            app.m_y = [ymin ymin ymin; ymax ymax ymax];
            app.m_z = [zmin zmin zmin; zmin zmin zmin];


            % Display all data from 3d points directly on the new drawing
            correctedFigure(app)
            % hold(app.UIAxes_3, 'on');
            % % Create surface objects and set their properties
            % % surface(app.UIAxes_3, 'XData', app.m_x, 'YData', app.m_y, 'ZData', app.m_z, 'CData', app.I_new_middle, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
            % % 
            % % surface(app.UIAxes_3, 'XData', app.r_x, 'YData', app.r_y, 'ZData', app.r_z, 'CData', app.I_new_right, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
            % % surface(app.UIAxes_3, 'XData', app.l_x, 'YData', app.l_y, 'ZData', app.l_z, 'CData', app.I_new_left, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
            % % surface(app.UIAxes_3, 'XData', app.up_x, 'YData', app.up_y, 'ZData', app.up_z, 'CData', app.I_new_up, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
            % % surface(app.UIAxes_3, 'XData', app.b_x, 'YData', app.b_y, 'ZData', app.b_z, 'CData', app.I_new_down, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
            % 
            % %set(app.UIFigure, 'Color', 'none');
            % %set(app.UIFigure, 'Renderer', 'painters');
            % %axis(app.UIAxes_3, 'tight');
            % rotate3d(app.UIAxes_3,'on')
            % %view(app.UIAxes_3, 95,-83)
            % % Hold off the specified UIAxes
            % 
            % hold(app.UIAxes_3, 'off');


        end

        % Button pushed function: Button_18
        function next2(app, event)
            app.TabGroup.SelectedTab = app.threeDimensionalPlot;
        end

        % Button pushed function: Button_23
        function prev2(app, event)
            app.TabGroup.SelectedTab = app.VPTab;
        end

        % Button pushed function: ClickheretoselectaforegroundobjectButton
        function chooseforeground(app, event)
            app.chooseForeground = true;
             app.rois = markMultipleROIs(app.imagedata, app);
        end

        % Button pushed function: Button_24
        function correctedFigure(app, event)
        %This function performs a coordinate transformation to align the 3D
        %Scene's coordinates with MATLAB's coordinates. This way, the rotations
        %and interactions with the plot seem a lot more natural.
                
        camPos = 1e3 .* [-3.5895 0.0109 -0.0769];
        camTarget = [0 0 0];
        camUpVector = [0 0 1];
        % Create transformation matrix using the consistent camera parameters
        zAxis = camTarget - camPos;
        zAxis = zAxis / norm(zAxis); % Normalize to unit vector
        xAxis = cross(camUpVector, zAxis);
        xAxis = xAxis / norm(xAxis); % Normalize to unit vector
        yAxis = cross(zAxis, xAxis); % This is already normalized
        T = eye(4);
        T(1:3, 1:3) = [xAxis; yAxis; zAxis];
        % Apply the transformation to the data
        transformCoords = @(x, y, z) (T * [x(:)'; y(:)'; z(:)'; ones(1, numel(x))]);
        % Apply to each surface's data
        m_coords_new = transformCoords(app.m_x, app.m_y, app.m_z);
        up_coords_new = transformCoords(app.up_x, app.up_y, app.up_z);
        b_coords_new = transformCoords(app.b_x, app.b_y, app.b_z);
        r_coords_new = transformCoords(app.r_x, app.r_y, app.r_z);
        l_coords_new = transformCoords(app.l_x, app.l_y, app.l_z);
        % Transform foreground objects consistently
        rois_coords_new = {};
        if app.chooseForeground
            for i = 1:app.rois.count
                rois_coords_new{i} = transformCoords(app.rois.middle_mx{i}, app.rois.middle_my{i}, app.rois.middle_mz{i});
            end
        end
        % Reshape the transformed coordinates back to their original shape
        m_x_new = reshape(m_coords_new(1, :), size(app.m_x));
        m_y_new = reshape(m_coords_new(2, :), size(app.m_y));
        m_z_new = reshape(m_coords_new(3, :), size(app.m_z));
        up_x_new = reshape(up_coords_new(1, :), size(app.up_x));
        up_y_new = reshape(up_coords_new(2, :), size(app.up_y));
        up_z_new = reshape(up_coords_new(3, :), size(app.up_z));
        b_x_new = reshape(b_coords_new(1, :), size(app.b_x));
        b_y_new = reshape(b_coords_new(2, :), size(app.b_y));
        b_z_new = reshape(b_coords_new(3, :), size(app.b_z));
        r_x_new = reshape(r_coords_new(1, :), size(app.r_x));
        r_y_new = reshape(r_coords_new(2, :), size(app.r_y));
        r_z_new = reshape(r_coords_new(3, :), size(app.r_z));
        l_x_new = reshape(l_coords_new(1, :), size(app.l_x));
        l_y_new = reshape(l_coords_new(2, :), size(app.l_y));
        l_z_new = reshape(l_coords_new(3, :), size(app.l_z));
        if app.chooseForeground
            for i = 1:app.rois.count
                app.rois.middle_mx_new{i} = reshape(rois_coords_new{i}(1, :), size(app.rois.middle_mx{i}));
                app.rois.middle_my_new{i} = reshape(rois_coords_new{i}(2, :), size(app.rois.middle_my{i}));
                app.rois.middle_mz_new{i} = reshape(rois_coords_new{i}(3, :), size(app.rois.middle_mz{i}));
            end
        end
        % Plotting
        hold(app.UIAxes_3, 'on');
        surf(app.UIAxes_3, m_x_new, m_y_new, m_z_new, 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.I_new_middle);
        surf(app.UIAxes_3, up_x_new, up_y_new, up_z_new, 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.I_new_up);
        surf(app.UIAxes_3, b_x_new, b_y_new, b_z_new, 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.I_new_down);
        surf(app.UIAxes_3, r_x_new, r_y_new, r_z_new, 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.I_new_right);
        surf(app.UIAxes_3, l_x_new, l_y_new, l_z_new, 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.I_new_left);
        if app.chooseForeground
            for i = 1:app.rois.count
                surface_handle{i} = surf(app.UIAxes_3, app.rois.middle_mx_new{i}, app.rois.middle_my_new{i}, app.rois.middle_mz_new{i},  ...
                    'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', app.rois.I_new_poly{i}(:, :, 1:3));
                set(surface_handle{i}, 'AlphaData', app.rois.I_new_poly{i}(:, :, 4), 'FaceAlpha', 'texturemap');
            end
        end
        rotate3d(app.UIAxes_3, 'on');
        view(app.UIAxes_3, 14.95, 1.23);
        set(app.UIAxes_3, 'Color', 'none');
        hold(app.UIAxes_3, 'off');

    
        end

        % Callback function: XSlider, XSlider
        function adjustAzimuth(app, event)
            value = event.Value;
            view(app.UIAxes_3, 14.95 - value,1.23);
        end

        % Value changing function: YSlider
        function adjustElevation(app, event)
            value = event.Value;
            view(app.UIAxes_3, 14.95, 1.23 - value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons and backgrounds', 'vectorbg.png');
            app.Button_7 = uibutton(app.HomeTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @showImageOil, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'images', 'oil-painting.png');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.HomeTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @showImageTUM, true);
            app.Button_8.Icon = fullfile(pathToMLAPP, 'images', 'uhren-turm.jpg');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.HomeTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @showImageMall, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'images', 'shopping-mall.png');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.HomeTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @showImageDrawing, true);
            app.Button_10.Icon = fullfile(pathToMLAPP, 'images', 'simple-room.png');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.HomeTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @showImageMoscow, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'images', 'metro-station.png');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.HomeTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @showImageSagrada, true);
            app.Button_12.Icon = fullfile(pathToMLAPP, 'images', 'sagrada_familia.png');
            app.Button_12.Text = '';
            app.WelcomePleasechooseasceneforthetourLabel = uilabel(app.HomeTab);
            app.WelcomePleasechooseasceneforthetourLabel.Text = {'Welcome.'; 'Please choose a scene for the tour.'};
            app.OrUploadAnImageButton = uibutton(app.HomeTab, 'push');
            app.OrUploadAnImageButton.ButtonPushedFcn = createCallbackFcn(app, @uploadImage, true);
            app.OrUploadAnImageButton.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '1337310-200.png');
            app.OrUploadAnImageButton.Text = 'Or Upload An Image   ';
            app.FGMaskTab = uitab(app.TabGroup);
            app.FGMaskTab.Title = 'FGMask';
            app.UIAxes = uiaxes(app.FGMaskTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.FGMaskTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.FGMaskTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.Button_13 = uibutton(app.FGMaskTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @clearAll, true);
            app.Button_13.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '25694.png');
            app.Button_13.Text = '';
            app.Label = uilabel(app.FGMaskTab);
            app.Label.Text = 'Select foreground objects by marking points around a region of interest. ';
            app.TofinishaselectiondoubleclickonthelastpointLabel = uilabel(app.FGMaskTab);
            app.TofinishaselectiondoubleclickonthelastpointLabel.Text = 'To finish a selection, double-click on the last point.';
            app.BinaryMaskLabel = uilabel(app.FGMaskTab);
            app.BinaryMaskLabel.Text = 'Binary Mask:';
            app.BinaryMaskLabel_2 = uilabel(app.FGMaskTab);
            app.BinaryMaskLabel_2.Text = 'Regions of Interest:';
            app.STEP1Label = uilabel(app.FGMaskTab);
            app.STEP1Label.Text = 'STEP 1';
            app.Button_14 = uibutton(app.FGMaskTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @next, true);
            app.Button_14.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '59209.png');
            app.Button_14.Text = '';
            app.WhendonemovetonextstepLabel = uilabel(app.FGMaskTab);
            app.WhendonemovetonextstepLabel.Text = 'When done, move to next step.';
            app.ClickheretoselectaforegroundobjectButton = uibutton(app.FGMaskTab, 'push');
            app.ClickheretoselectaforegroundobjectButton.ButtonPushedFcn = createCallbackFcn(app, @chooseforeground, true);
            app.ClickheretoselectaforegroundobjectButton.Text = 'Click here to select a foreground object';
            app.VPTab = uitab(app.TabGroup);
            app.VPTab.Title = 'VP';
            app.UIAxes_2 = uiaxes(app.VPTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Button_15 = uibutton(app.VPTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @clearAll, true);
            app.Button_15.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '25694.png');
            app.Button_15.Text = '';
            app.Label_2 = uilabel(app.VPTab);
            app.Label_2.Text = {'Mark a vanishing point and the background of the image.'; 'You can also move around and scale the background rectangle'; 'after drawing the lines to get a better result!'};
            app.STEP2Label = uilabel(app.VPTab);
            app.STEP2Label.Text = 'STEP 2';
            app.Button_17 = uibutton(app.VPTab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @prev, true);
            app.Button_17.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '59210.png');
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.VPTab, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @next2, true);
            app.Button_18.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '59209.png');
            app.Button_18.Text = '';
            app.ClickheretoselectavanishingpointButton = uibutton(app.VPTab, 'push');
            app.ClickheretoselectavanishingpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoselectavanishingpointButtonPushed, true);
            app.ClickheretoselectavanishingpointButton.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', 'selectVP.png');
            app.ClickheretoselectavanishingpointButton.Text = 'Click here to select a vanishing point';
            app.ClickheretoselectabackgroundButton = uibutton(app.VPTab, 'push');
            app.ClickheretoselectabackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoselectabackgroundButtonPushed, true);
            app.ClickheretoselectabackgroundButton.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', 'selectBackground.png');
            app.ClickheretoselectabackgroundButton.Text = 'Click here to select a background';
            app.ClickheretocomputethemeshButton = uibutton(app.VPTab, 'push');
            app.ClickheretocomputethemeshButton.ButtonPushedFcn = createCallbackFcn(app, @ClickheretocomputethemeshButtonPushed, true);
            app.ClickheretocomputethemeshButton.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', 'CreateSpiderMesh.png');
            app.ClickheretocomputethemeshButton.Text = 'Click here to compute the mesh';
            app.Button_25 = uibutton(app.VPTab, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', 'Generate3D Scene.png');
            app.Button_25.Text = '';
            app.threeDimensionalPlot = uitab(app.TabGroup);
            app.threeDimensionalPlot.Title = '3D';
            app.UIAxes_3 = uiaxes(app.threeDimensionalPlot);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Button_22 = uibutton(app.threeDimensionalPlot, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @clearAll, true);
            app.Button_22.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '25694.png');
            app.Button_22.Text = '';
            app.Label_4 = uilabel(app.threeDimensionalPlot);
            app.Label_4.Text = {'Your 3D Scene is ready!'; 'Play around with your image by rotating and seeing new perspectives.'; 'If your desired results are not obtained, try with another choice of vanishing point.'};
            app.STEP3Label_2 = uilabel(app.threeDimensionalPlot);
            app.STEP3Label_2.Text = 'STEP 3';
            app.Button_23 = uibutton(app.threeDimensionalPlot, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @prev2, true);
            app.Button_23.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', '59210.png');
            app.Button_23.Text = '';
            app.AdjustAzimuthandElevationPanel = uipanel(app.threeDimensionalPlot);
            app.AdjustAzimuthandElevationPanel.Title = 'Adjust Azimuth and Elevation';
            app.YSlider = uislider(app.AdjustAzimuthandElevationPanel);
            app.YSlider.Limits = [-15 15];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @adjustElevation, true);
            app.YSlider.MinorTicks = [];
            app.XSlider = uislider(app.AdjustAzimuthandElevationPanel);
            app.XSlider.Limits = [-30 30];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @adjustAzimuth, true);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @adjustAzimuth, true);
            app.XSlider.MinorTicks = [];
            app.Button_24 = uibutton(app.AdjustAzimuthandElevationPanel, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @correctedFigure, true);
            app.Button_24.Icon = fullfile(pathToMLAPP, 'icons and backgrounds', 'resetView.png');
            app.Button_24.Text = '';
            app.Image2 = uiimage(app.AdjustAzimuthandElevationPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons and backgrounds', 'leftRight.png');
            app.Image3 = uiimage(app.AdjustAzimuthandElevationPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icons and backgrounds', 'upDown.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIPexe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end